classdef mEMbrain_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mEMbrainUIFigure                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NeurontoMembraneTab             matlab.ui.container.Tab
        ConvertNeuronGroundTruthDatatoMembraneGroundTruthDataLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NeuronGroundTruthLabel          matlab.ui.control.Label
        SelectInputDirectoryButton      matlab.ui.control.Button
        MembraneGroundTruthLabel        matlab.ui.control.Label
        SelectOutputDirectoryButton     matlab.ui.control.Button
        MembranePropertiesLabel         matlab.ui.control.Label
        SetmembranepixelstoButtonGroup  matlab.ui.container.ButtonGroup
        zeroButton                      matlab.ui.control.ToggleButton
        oneButton                       matlab.ui.control.ToggleButton
        SetmembranethicknesstoLabel     matlab.ui.control.Label
        borderThicknessSpinner          matlab.ui.control.Spinner
        MembraneGroundTruthPreviewLabel  matlab.ui.control.Label
        ConvertButton                   matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        BorderPreviewAxes               matlab.ui.control.UIAxes
        FillExtracellularSpaceECSSwitchLabel  matlab.ui.control.Label
        FillExtracellularSpaceECSSwitch  matlab.ui.control.Switch
        MembranetoSkeletonTab           matlab.ui.container.Tab
        ConvertMembraneGroundTruthDatatoSkeletonGroundTruthDataLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        MembraneGroundTruthLabel_2      matlab.ui.control.Label
        SelectInputDirectoryButton_2    matlab.ui.control.Button
        SkeletonGroundTruthLabel        matlab.ui.control.Label
        SelectOutputDirectoryButton_2   matlab.ui.control.Button
        SkeletonGroundTruthPreviewLabel  matlab.ui.control.Label
        ConvertButton_2                 matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        BorderPreviewAxes_2             matlab.ui.control.UIAxes
        NewProjectTab                   matlab.ui.container.Tab
        PickUpaProjectTab               matlab.ui.container.Tab
        SecondTabButton                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        inputDirectory % Neuron GT input directory (set in SelectInputDirectoryButtonPushed)
        outputDirectory % Border GT output directory (set in SelectOutputDirectoryButtonPushed)
        borderValue % Pixel value assigned to borders (0 or 1)
        borderThickness % Size of erosion/dilation structure element neighborhood (the "thickness" of the borders)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.BorderPreviewAxes, []);
            xlabel(app.BorderPreviewAxes, []);
            ylabel(app.BorderPreviewAxes, []);
            app.BorderPreviewAxes.XAxis.TickLabels = {};
            app.BorderPreviewAxes.YAxis.TickLabels = {};
        end

        % Button pushed function: SelectInputDirectoryButton
        function SelectInputDirectoryButtonPushed(app, event)
            % When the "Select Input Directory" button is pushed, the user
            % selects a directory for the neuron GT (TODO: expand to
            % reverse problem?) whose images will be processed when the
            % "Convert" button is pressed.
            
            selectInputDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectInputDirectory ~= 0
                app.inputDirectory = selectInputDirectory;
                app.SelectInputDirectoryButton.Text = strcat('Selected Input Directory: ', app.inputDirectory);
            end
            
            % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.inputDirectory) && ~isempty(app.outputDirectory)
                app.ConvertButton.Enable = 'on';
            end
            
        end

        % Button pushed function: SelectOutputDirectoryButton
        function SelectOutputDirectoryButtonPushed(app, event)
            % When the "Select Output Directory" button is pushed, the user
            % selects a directory for the border GT (TODO: expand to
            % reverse problem?) where images will be saved when the
            % "Convert" button is pressed.
            
            selectOutputDirectory = uigetdir;
            drawnow;
            figure(app.mEMbrainUIFigure) % Brings mEMbrain app back into focus
            if selectOutputDirectory ~= 0
                app.outputDirectory = selectOutputDirectory;
                app.SelectOutputDirectoryButton.Text = strcat('Selected Output Directory: ', app.outputDirectory);
            end
            
             % Check if both input directory and output directory have been
            % specified. If yes, then enable the "Convert" button
            
            if ~isempty(app.inputDirectory) && ~isempty(app.outputDirectory)
                app.ConvertButton.Enable = 'on';
            end
            
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            % When the "Convert" button is pushed, the app reads the values
            % of the "Set Border Pixels To..." button group and the "Border
            % Thickness" spinner, reads the images in the input directory,
            % processes them with erosion/dilation, and saves them to the
            % output directory
             
            % Check which toggle button for the value of border pixels is
            % selected
            switch app.zeroButton.Value
                case 0
                    app.borderValue = 1;
                case 1
                    app.borderValue = 0;
                otherwise
                    app.borderValue = 1;
            end
            
            % Get value of border thickness spinner
            app.borderThickness = app.borderThicknessSpinner.Value;
            
            % Read input directory images (PNG format)
            inputFilePattern = fullfile(app.inputDirectory, '*.png');
            neuronGTFiles = dir(inputFilePattern);
            nFiles = length(neuronGTFiles);
            
            % Open a progress bar
            fig = uifigure;
            progressBar = uiprogressdlg(fig, 'Title', 'Please Wait', 'Message', 'Reading neuron ground truth images');
            
            for imgIndex = 1:nFiles
                currentfilename = neuronGTFiles(imgIndex).name;
                fullfilename = fullfile(app.inputDirectory, currentfilename);
                currentimage = imread(fullfilename);
                neuronGT{imgIndex} = currentimage;
                progressBar.Value = imgIndex/(2*nFiles); % Update the value of the progress bar (finishing the Neuron GT is halfway)
            end
            
            % Convert each neuron GT image into border GT image and save it
            % to the output directory
            
            progressBar.Message = "Converting neuron ground truth to border ground truth";
            
            for imgIndex = 1:nFiles
                currentNeuronGT = neuronGT{imgIndex};
                grayNeuronGT = unique_rgb(currentNeuronGT); % Convert RGB image into grayscale image
                structuringElement = ones(app.borderThickness, app.borderThickness);
                
                newBorderGT = convertNeuronToMembrane(grayNeuronGT, structuringElement, app.borderValue, app.FillExtracellularSpaceECSSwitch.Value) % Conversion to border GT
                
                % Show the first border GT in the preview axes                
                if imgIndex == 1
                    app.BorderPreviewAxes.Visible = 'on';
                    imshow(newBorderGT, 'parent', app.BorderPreviewAxes)
                end
                
                borderFileName = strcat(erase(neuronGTFiles(imgIndex).name, ".png"), "_border.png");
                borderFullFileName = fullfile(app.outputDirectory, borderFileName);
                imwrite(newBorderGT, borderFullFileName);
                progressBar.Value = 0.5 + imgIndex/(2*nFiles);
            end
            
            close(progressBar)
            close(fig)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % When the "Reset" button is pushed, all app properties should
            % be emptied, Border Preview Axes are cleared and made
            % invisible, and "Convert" button is disabled
            
            app.inputDirectory = [];
            app.outputDirectory = [];
            app.borderValue = [];
            app.borderThickness = [];
            
            cla(app.BorderPreviewAxes)
            app.BorderPreviewAxes.Visible = "off";
            
            app.ConvertButton.Enable = "off";
            
            app.borderThicknessSpinner.Value = 3;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mEMbrainUIFigure = uifigure('Visible', 'off');
            app.mEMbrainUIFigure.Name = 'mEMbrain';
            app.TabGroup = uitabgroup(app.mEMbrainUIFigure);
            app.NeurontoMembraneTab = uitab(app.TabGroup);
            app.NeurontoMembraneTab.Title = 'Neuron to Membrane';
            app.ConvertNeuronGroundTruthDatatoMembraneGroundTruthDataLabel = uilabel(app.NeurontoMembraneTab);
            app.ConvertNeuronGroundTruthDatatoMembraneGroundTruthDataLabel.Text = 'Convert Neuron Ground Truth Data to Membrane Ground Truth Data';
            app.Label = uilabel(app.NeurontoMembraneTab);
            app.Label.Text = 'Please select the ground truth directory to convert the neuron data to membrane data';
            app.NeuronGroundTruthLabel = uilabel(app.NeurontoMembraneTab);
            app.NeuronGroundTruthLabel.Text = 'Neuron Ground Truth';
            app.SelectInputDirectoryButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.SelectInputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputDirectoryButtonPushed, true);
            app.SelectInputDirectoryButton.Text = 'Select Input Directory...';
            app.MembraneGroundTruthLabel = uilabel(app.NeurontoMembraneTab);
            app.MembraneGroundTruthLabel.Text = 'Membrane Ground Truth';
            app.SelectOutputDirectoryButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory...';
            app.MembranePropertiesLabel = uilabel(app.NeurontoMembraneTab);
            app.MembranePropertiesLabel.Text = 'Membrane Properties';
            app.SetmembranepixelstoButtonGroup = uibuttongroup(app.NeurontoMembraneTab);
            app.SetmembranepixelstoButtonGroup.Title = 'Set membrane pixels to...';
            app.zeroButton = uitogglebutton(app.SetmembranepixelstoButtonGroup);
            app.zeroButton.Text = '0';
            app.oneButton = uitogglebutton(app.SetmembranepixelstoButtonGroup);
            app.oneButton.Text = '1';
            app.oneButton.Value = true;
            app.SetmembranethicknesstoLabel = uilabel(app.NeurontoMembraneTab);
            app.SetmembranethicknesstoLabel.Text = 'Set membrane thickness to...';
            app.borderThicknessSpinner = uispinner(app.NeurontoMembraneTab);
            app.borderThicknessSpinner.Limits = [1 Inf];
            app.borderThicknessSpinner.Value = 3;
            app.MembraneGroundTruthPreviewLabel = uilabel(app.NeurontoMembraneTab);
            app.MembraneGroundTruthPreviewLabel.Text = 'Membrane Ground Truth Preview';
            app.ConvertButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Enable = 'off';
            app.ConvertButton.Text = 'Convert';
            app.ResetButton = uibutton(app.NeurontoMembraneTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BorderPreviewAxes = uiaxes(app.NeurontoMembraneTab);
            title(app.BorderPreviewAxes, 'Title')
            xlabel(app.BorderPreviewAxes, 'X')
            ylabel(app.BorderPreviewAxes, 'Y')
            app.BorderPreviewAxes.Visible = 'off';
            app.FillExtracellularSpaceECSSwitchLabel = uilabel(app.NeurontoMembraneTab);
            app.FillExtracellularSpaceECSSwitchLabel.Text = 'Fill Extracellular-Space (ECS)';
            app.FillExtracellularSpaceECSSwitch = uiswitch(app.NeurontoMembraneTab, 'slider');
            app.MembranetoSkeletonTab = uitab(app.TabGroup);
            app.MembranetoSkeletonTab.Title = 'Membrane to Skeleton';
            app.ConvertMembraneGroundTruthDatatoSkeletonGroundTruthDataLabel = uilabel(app.MembranetoSkeletonTab);
            app.ConvertMembraneGroundTruthDatatoSkeletonGroundTruthDataLabel.Text = 'Convert Membrane Ground Truth Data to Skeleton Ground Truth Data';
            app.Label_2 = uilabel(app.MembranetoSkeletonTab);
            app.Label_2.Text = 'Please select the ground truth directory to convert the membrane data to skeleton data';
            app.MembraneGroundTruthLabel_2 = uilabel(app.MembranetoSkeletonTab);
            app.MembraneGroundTruthLabel_2.Text = 'Membrane Ground Truth';
            app.SelectInputDirectoryButton_2 = uibutton(app.MembranetoSkeletonTab, 'push');
            app.SelectInputDirectoryButton_2.Text = 'Select Input Directory...';
            app.SkeletonGroundTruthLabel = uilabel(app.MembranetoSkeletonTab);
            app.SkeletonGroundTruthLabel.Text = 'Skeleton Ground Truth';
            app.SelectOutputDirectoryButton_2 = uibutton(app.MembranetoSkeletonTab, 'push');
            app.SelectOutputDirectoryButton_2.Text = 'Select Output Directory...';
            app.SkeletonGroundTruthPreviewLabel = uilabel(app.MembranetoSkeletonTab);
            app.SkeletonGroundTruthPreviewLabel.Text = 'Skeleton Ground Truth Preview';
            app.ConvertButton_2 = uibutton(app.MembranetoSkeletonTab, 'push');
            app.ConvertButton_2.Enable = 'off';
            app.ConvertButton_2.Text = 'Convert';
            app.ResetButton_2 = uibutton(app.MembranetoSkeletonTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.BorderPreviewAxes_2 = uiaxes(app.MembranetoSkeletonTab);
            title(app.BorderPreviewAxes_2, 'Title')
            xlabel(app.BorderPreviewAxes_2, 'X')
            ylabel(app.BorderPreviewAxes_2, 'Y')
            app.BorderPreviewAxes_2.Visible = 'off';
            app.NewProjectTab = uitab(app.TabGroup);
            app.NewProjectTab.Title = 'New Project';
            app.PickUpaProjectTab = uitab(app.TabGroup);
            app.PickUpaProjectTab.Title = 'Pick Up a Project';
            app.SecondTabButton = uibutton(app.PickUpaProjectTab, 'state');
            app.SecondTabButton.Text = 'Second Tab';
            % Show the figure after all components are created
            app.mEMbrainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mEMbrain_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mEMbrainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mEMbrainUIFigure)
        end
    end
end